classdef SFE_IFC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SFE_IFC_GUI                    matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UITable                        matlab.ui.control.Table
        SelectFloodEventsPanel         matlab.ui.container.Panel
        InputDataPanel                 matlab.ui.container.Panel
        SelectFileButton               matlab.ui.control.Button
        SampleMethodButtonGroup        matlab.ui.container.ButtonGroup
        POTButton                      matlab.ui.control.RadioButton
        AMSButton                      matlab.ui.control.RadioButton
        POTParametersPanel             matlab.ui.container.Panel
        ThresholdPanel                 matlab.ui.container.Panel
        ThresholdLabel                 matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        PPYLabel                       matlab.ui.control.Label
        PPYEditField                   matlab.ui.control.NumericEditField
        ADtestLabel                    matlab.ui.control.Label
        ADtestEditField                matlab.ui.control.NumericEditField
        IntervalPanel                  matlab.ui.container.Panel
        MinIntervalLabel               matlab.ui.control.Label
        MinIntervalEditField           matlab.ui.control.NumericEditField
        CalculateParametersButton      matlab.ui.control.Button
        ValidatationPanel              matlab.ui.container.Panel
        DispersionindexEditFieldLabel  matlab.ui.control.Label
        DispersionindexEditField       matlab.ui.control.NumericEditField
        SelectEventsButton             matlab.ui.control.Button
        IdentifyFloodCharacteristicsPanel  matlab.ui.container.Panel
        RecessionCurvesPanel           matlab.ui.container.Panel
        ExtractRecessionsPanel         matlab.ui.container.Panel
        MovingaverageLabel             matlab.ui.control.Label
        MovingaverageEditField         matlab.ui.control.NumericEditField
        Min_durationLabel              matlab.ui.control.Label
        Min_durationEditField          matlab.ui.control.NumericEditField
        OptimizeParameterButton        matlab.ui.control.Button
        RecessionAnalysisMethodsButtonGroup  matlab.ui.container.ButtonGroup
        MailletButton                  matlab.ui.control.RadioButton
        CoutagneButton                 matlab.ui.control.RadioButton
        WittenbergButton               matlab.ui.control.RadioButton
        NoParameterButton              matlab.ui.control.RadioButton
        DeepBaseflowLabel              matlab.ui.control.Label
        DeepBaseflowEditField          matlab.ui.control.NumericEditField
        CalculateParametersPlotButton  matlab.ui.control.Button
        bEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        IdentifyFloodCharacteristicsButton  matlab.ui.control.Button
        SaveFloodEventsButton          matlab.ui.control.Button
        GraphicBoxSettingsPanel        matlab.ui.container.Panel
        CleangraphicboxButton          matlab.ui.control.Button
        ScaleYDropDownLabel            matlab.ui.control.Label
        ScaleYDropDown                 matlab.ui.control.DropDown
        ComprehensivePlotCheckBox      matlab.ui.control.CheckBox
        GoodnessofFitPanel             matlab.ui.container.Panel
        SSELabel                       matlab.ui.control.Label
        SSEEditField                   matlab.ui.control.NumericEditField
        RsquareLabel                   matlab.ui.control.Label
        RsquareEditField               matlab.ui.control.NumericEditField
        RMSELabel                      matlab.ui.control.Label
        RMSEEditField                  matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            global filename
            if isfolder('data')~=1
                mkdir('data')
            end
            
            [filename, pathname, ~] = uigetfile( ...
                {'*.xlsx','excel (*.xlsx)'; ...
                '*.xls','excel (*.xls)'}, ...
                'Pick a file');
            if filename ~=0
                [~,sheet]=xlsfinfo([pathname,filename]);
                rawdata=readcell([pathname,filename]);
                
                f = figure('Name','Observed Discharge','Position',[400 50 800 550]);
                t = uitable(f,'ColumnName', rawdata(1,1:4),'ColumnWidth',{100},'Position',[10 10 790 540]);
                set(t,'data',rawdata(2:end,:))
                Y_M_D_F=cell2mat(rawdata(2:end,:));
                Area=str2double(cell2mat(sheet));
                save('data\Rawdata.mat','Y_M_D_F','Area')
                
            else
                
                filtroquest = questdlg(['Data file was not imported. Do the procedure again? '], ...
                   	'Data file', ...
                   	'Open file','Exit','Exit');
                switch filtroquest
                    case 'Open file'
                        SelectFileButtonPushed(app, event)
                    case 'Exit'
                end
            end
        end

        % Button pushed function: CalculateParametersButton
        function CalculateParametersButtonPushed(app, event)
            load ('data\Rawdata.mat','Y_M_D_F','Area')
            %             flow_datenum=datenum(Y_M_D_F(:,1:3));
            %             flow_datevec=datevec(flow_datenum);
            global date_flow
            date_flow=Y_M_D_F;
            area_km=Area;
            
            if app.POTButton.Value==1
                
                interval=floor(5+log(area_km/1.609^2));
                app.MinIntervalEditField.Value=interval;
                [output_para,figuredata] = Auto_select_thre(date_flow,interval);
                app.ThresholdEditField.Value=output_para(1);
                app.ADtestEditField.Value=output_para(2);
                app.PPYEditField.Value=output_para(3);
                
                peaks_datenum = selectpeaks( date_flow ,output_para(1),interval );
                tempdate=datevec(peaks_datenum(:,5));
                date_peaks=[tempdate(:,1:3),peaks_datenum(:,2)];
                output_para2 = validatation_poisson_ks(date_peaks,date_flow);
                app.DispersionindexEditField.Value=output_para2(1);
                
                cla(app.UIAxes,"reset")
                yyaxis(app.UIAxes,"left")
                plot(app.UIAxes,figuredata(:,1),figuredata(:,2),'LineWidth',1.5)
                hold(app.UIAxes,"on")
                plot(app.UIAxes,output_para(1),output_para(2),'ro','MarkerSize',8)
                ylabel(app.UIAxes,'AD statistic');
                yyaxis(app.UIAxes,"right")
                plot(app.UIAxes,figuredata(:,1),figuredata(:,3),'LineWidth',1.5)
                ylabel(app.UIAxes,'Peaks per year');
                
                xlabel(app.UIAxes, 'Threshold(m^3/s)')
                
                set(app.UIAxes,'FontSize',15,'LineWidth',1.5);
                app.UIAxes.Box = 'on';
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                app.ScaleYDropDown.Value=app.UIAxes.YScale;
                app.ScaleYDropDown.Enable='off';


            end
        end

        % Button pushed function: SelectEventsButton
        function SelectEventsButtonPushed(app, event)
            global  date_flow s_e_date_q  tablename
            load ('data\Rawdata.mat','Area')
            if app.POTButton.Value==1
                
                peaks_datenum = selectpeaks( date_flow ,app.ThresholdEditField.Value,app.MinIntervalEditField.Value );
            else
                peaks_datenum=AMS_sample(date_flow) ;
            end
            save('data\peaks_datenum.mat',"peaks_datenum")
            [ s_e_date_q,dura,~] = starenddate( date_flow,peaks_datenum,floor(1.5*(5+log(Area/1.609^2))));
            %% 20200108 post-process
            %             slope=(s_e_date_q(:,4)-s_e_date_q(:,8))./dura(:,1);
            %             indexslope1=find(slope>100);
            %
            % %             peaks_datenum1=peaks_datenum;
            %             %             s_e_date_q1=s_e_date_q;
            %             if isempty(indexslope1)==0
            %                 if indexslope1(1)~=1
            %                     peaks_datenum(indexslope1-1,[3 6])=peaks_datenum(indexslope1,[3 6]);
            %                     peaks_datenum(indexslope1,:)=[];
            %                 end
            %             end
            %             [ s_e_date_q,dura,~] = starenddate( date_flow,peaks_datenum,app.F_BtimeEditField.Value);
            count_jj=[];
            for jj=1:size(peaks_datenum,1)
                if 3/4*peaks_datenum(jj,2)<=max(s_e_date_q(jj,[4 8]),[],2)
                    count_jj(jj,1)=jj;
                end
            end
            count_jj(count_jj==0)=[];
            s_e_date_q(count_jj,:)=[];
            dura(count_jj,:)=[];
            
            %              slope=(s_e_date_q(:,4)-s_e_date_q(:,8))./dura(:,1);
            %             indexslope2=find(slope<-100);
            %             if isempty(indexslope2)==0
            %                 if indexslope2(end)~=length(slope)
            %                     peaks_datenum(indexslope2+1,[1 4])=peaks_datenum(indexslope2,[1 4]);
            %                     peaks_datenum(indexslope2,:)=[];
            %                 end
            %             end
            %             [ s_e_date_q,dura,~] = starenddate( date_flow,peaks_datenum,app.F_BtimeEditField.Value);
            
            %             for ii=1:length(indexslope)
            %                 if indexslope(1)~=1
            %                     peaks_datenum(indexslope(ii)-(ii-1)-1,[3 6])=peaks_datenum1(indexslope(ii),[3 6]);
            %                     peaks_datenum(indexslope(ii)-(ii-1),:)=[];
            %                     s_e_date_q(indexslope(ii)-(ii-1)-1,5:8)=s_e_date_q1(indexslope(ii),5:8);
            %                     s_e_date_q(indexslope(ii)-(ii-1),:)=[];
            %                 end
            %             end
            [oldflood_p,oldflood_f,T1] = oldfloodevents( date_flow,s_e_date_q(:,[1 2 3 5 6 7]),dura(:,1) );
            set(app.UITable,'data',T1);
            tablename=1;
            mkdir('data\floodevents_features')
            save('data\floodevents_features\rawfloodeventsdata.mat','oldflood_p','oldflood_f','T1')
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            cla(app.UIAxes,'reset')
            indices = event.Indices;
            app.SSEEditField.Enable='off';
            app.RsquareEditField.Enable='off';
            app.RMSEEditField.Enable='off';
            global date_flow tablename
            if app.ComprehensivePlotCheckBox.Value==0
                if tablename==1
                    flow_datenum=datenum(date_flow(:,1:3));
                    load ('data\floodevents_features\rawfloodeventsdata.mat','T1')
                    stardatenun=datenum(T1.Stardate(indices(1)));
                    enddatenun=datenum(T1.Enddate(indices(1)));
                    duration=T1.Duration(indices(1));
                    temp=ceil(duration/3);
                    star_index=find(flow_datenum==stardatenun);
                    end_index=find(flow_datenum==enddatenun);
                    plot(app.UIAxes,datetime(datevec(flow_datenum(star_index-temp:end_index+temp)),'Format','MM-dd'),date_flow(star_index-temp:end_index+temp,4),'LineWidth',2);
                    hold(app.UIAxes,'on');
                    ylim=get(app.UIAxes,'Ylim'); % 获取当前图形的纵轴的范围
                    plot(app.UIAxes,datetime(datevec([flow_datenum(star_index),flow_datenum(star_index)]),'Format','MM-dd'),ylim,'m--','LineWidth',1.5);
                    plot(app.UIAxes,datetime(datevec([flow_datenum(end_index),flow_datenum(end_index)]),'Format','MM-dd'),ylim,'m--','LineWidth',1.5)
                    
                    
                elseif tablename==2
                    load('data\floodevents_features\floodcharacteristics.mat','T2','newflood_f','newflood_p')
                    stardate=T2.Stardate(indices(1));
                    enddate=T2.Enddate(indices(1));
                    flood_p=newflood_p{indices(1),1}(:,4);
                    plot(app.UIAxes,datetime(stardate:enddate,'Format','MM-dd'),flood_p,'LineWidth',2);
                    
                end
                
            else
                flow_datenum=datenum(date_flow(:,1:3));
                load ('data\floodevents_features\rawfloodeventsdata.mat','T1')
                stardatenun=datenum(T1.Stardate(indices(1)));
                enddatenun=datenum(T1.Enddate(indices(1)));
                duration=T1.Duration(indices(1));
                temp=ceil(duration/3);
                star_index=find(flow_datenum==stardatenun);
                end_index=find(flow_datenum==enddatenun);
                plot(app.UIAxes,datetime(datevec(flow_datenum(star_index-temp:end_index+temp)),'Format','MM-dd'),date_flow(star_index-temp:end_index+temp,4),'LineWidth',2);
                hold(app.UIAxes,'on');
                
                
                load('data\floodevents_features\floodcharacteristics.mat','T2','newflood_f','newflood_p')
                stardate=T2.Stardate(indices(1));
                enddate=T2.Enddate(indices(1));
                flood_p=newflood_p{indices(1),1}(:,4);
                plot(app.UIAxes,datetime(stardate:enddate,'Format','MM-dd'),flood_p,'r--','LineWidth',2);
                
                ylim=get(app.UIAxes,'Ylim'); % 获取当前图形的纵轴的范围
                plot(app.UIAxes,datetime(datevec([flow_datenum(star_index),flow_datenum(star_index)]),'Format','MM-dd'),ylim,'m--','LineWidth',1.5);
                plot(app.UIAxes,datetime(datevec([flow_datenum(end_index),flow_datenum(end_index)]),'Format','MM-dd'),ylim,'m--','LineWidth',1.5)
                
            end
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Q(m^3/s)')
            hold(app.UIAxes,'off')
            set(app.UIAxes,'FontSize',15,'LineWidth',1.5);
            app.UIAxes.Box = 'on';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.ScaleYDropDown.Value=app.UIAxes.YScale;
            app.ScaleYDropDown.Enable='off';
            
            
        end

        % Button pushed function: CalculateParametersPlotButton
        function CalculateParametersPlotButtonPushed(app, event)
            
            global date_flow s_e_date_q
            %   calculate deep baseflow
            deepbaseflow=Deepbaseflow(date_flow);
            deepbaseflow(deepbaseflow<=1)=1; %20200409修改
            app.DeepBaseflowEditField.Value=deepbaseflow;
            
            [rece_series,dura]=extract_recession(date_flow,app.Min_durationEditField.Value,app.MovingaverageEditField.Value);
            [posi,sort_num,MRCdata,COR_MRCdata]=MRC_auto(rece_series,dura);
            
            mkdir('data\recessions')
            save ('data\recessions\rece_series.mat','rece_series','posi','sort_num')
            save('data\recessions\MRCdata.mat','MRCdata','COR_MRCdata')
            cla(app.UIAxes,'reset')
            for ii=1:length(sort_num)
                plot(app.UIAxes,(posi(ii,1):posi(ii,2))',rece_series{sort_num(ii),1}(:,2),'Color',0.6*ones(1,3))
                hold(app.UIAxes,'on')
            end
            legend(app.UIAxes,'Original Recessions')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Q(m^3/s)')
            plot(app.UIAxes,COR_MRCdata(:,1),COR_MRCdata(:,2),'ro','DisplayName','MRC Data');
            box(app.UIAxes,'on')
            app.UIAxes.FontSize=15;
            %app.UIAxes.XLim=[0,ceil(1.1*max(MRCdata(:,1)))];
            %app.UIAxes.YLim=[floor(0.8*min(MRCdata(:,2))),ceil(1.5*max(MRCdata(:,2)))];
            % fit MRCdata
            global met
            global fit1  fit2  fit3
            [fit1,fit2,fit3,gof1,gof2,gof3] = CreateFit(COR_MRCdata);
            
            
%             if app.ComprehensivePlotCheckBox.Value==1
%                 fit_t1=COR_MRCdata(1,1):0.1:COR_MRCdata(end,1);
%                 fit_q1=fit1(fit_t1);
%                 fit_q2=fit2(fit_t1);
%                 fit_q3=fit3(fit_t1);
%                 plot(app.UIAxes,fit_t1,fit_q1,'b:','DisplayName',[app.MailletButton.Text,' -Fit'],'LineWidth',1.5)
%                 plot(app.UIAxes,fit_t1,fit_q2,'g-.','DisplayName',[app.CoutagneButton.Text,' -Fit'],'LineWidth',1.5)
%                 plot(app.UIAxes,fit_t1,fit_q3,'c--','DisplayName',[app.WittenbergButton.Text,' -Fit'],'LineWidth',1.5)
%             else
                
                if app.MailletButton.Value==1
                    met=1;
                    a=fit1.a;
                    app.aEditField.Value=a;
                    app.SSEEditField.Value=gof1.sse;
                    app.RsquareEditField.Value=gof1.rsquare;
                    app.RMSEEditField.Value=gof1.rmse;
                    fit_t1=COR_MRCdata(1,1):0.1:COR_MRCdata(end,1);
                    fit_q1=fit1(fit_t1);
                    plot(app.UIAxes,fit_t1,fit_q1,'b','DisplayName',[app.MailletButton.Text,' -Fit'],'LineWidth',1.5)
                end
                if app.CoutagneButton.Value==1
                    met=2;
                    
                    a=fit2.a;
                    b=fit2.b;
                    app.aEditField.Value=a;
                    app.bEditField.Value=b;
                    app.SSEEditField.Value=gof2.sse;
                    app.RsquareEditField.Value=gof2.rsquare;
                    app.RMSEEditField.Value=gof2.rmse;
                    fit_t2=COR_MRCdata(1,1):0.1:COR_MRCdata(end,1);
                    fit_q2=fit2(fit_t2);
                    plot(app.UIAxes,fit_t2,fit_q2,'b','DisplayName',[app.CoutagneButton.Text,' -Fit'],'LineWidth',1.5)
                end
                
                if app.WittenbergButton.Value==1
                    met=3;
                    a=fit3.a;
                    b=fit3.b;
                    app.aEditField.Value=a;
                    app.bEditField.Value=b;
                    app.SSEEditField.Value=gof3.sse;
                    app.RsquareEditField.Value=gof3.rsquare;
                    app.RMSEEditField.Value=gof3.rmse;
                    fit_t3=COR_MRCdata(1,1):0.1:COR_MRCdata(end,1);
                    fit_q3=fit3(fit_t3);
                    plot(app.UIAxes,fit_t3,fit_q3,'b','DisplayName',[app.WittenbergButton.Text,' -Fit'],'LineWidth',1.5)
                end
                %% 20200106 add no parameter methods
                if app.NoParameterButton.Value==1
                    met=4;
                    mrc_data=COR_MRCdata(:,2);
                    mrc_data1=mrc_data;
                    for kk=1:200
                        mrc_data1=smoothdata(mrc_data1,'movmean',3);
                        mrc_data1=[mrc_data(1);mrc_data1(2:end-1);mrc_data(end)];
                        diff_mre=diff(mrc_data1);
                        if isempty(diff_mre(diff_mre>0))==1
                            %                         kk
                            break
                        end
                    end
                    x=(COR_MRCdata(1,1):0.01:COR_MRCdata(end,1))';
                    y=interp1(COR_MRCdata(:,1),mrc_data1,x,'spline');
                    RE_COR_MRCdata=[x,y];
                    plot(app.UIAxes,x,y,'b','DisplayName','NoParemeter-Fit','LineWidth',1.5)
                    
                    save('data\recessions\RE_MRCdata.mat','RE_COR_MRCdata')
                    
                end
                %                         end
                if met==4
                    app.SSEEditField.Enable='off';
                    app.RsquareEditField.Enable='off';
                    app.RMSEEditField.Enable='off';
                else
                    app.SSEEditField.Enable='on';
                    app.RsquareEditField.Enable='on';
                    app.RMSEEditField.Enable='on';
                end
                
%                end
                app.UIAxes.YScale='log';
                app.ScaleYDropDown.Value=app.UIAxes.YScale;
                app.ScaleYDropDown.Enable='on';
           
        end

        % Value changed function: ScaleYDropDown
        function ScaleYDropDownValueChanged(app, event)
            value = app.ScaleYDropDown.Value;
            app.UIAxes.YScale=value;
        end

        % Button pushed function: CleangraphicboxButton
        function CleangraphicboxButtonPushed(app, event)
            cla(app.UIAxes,'reset')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Q(m^3/s)')
            app.UIAxes.Box = 'on';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.YScale='linear';
        end

        % Button pushed function: IdentifyFloodCharacteristicsButton
        function IdentifyFloodCharacteristicsButtonPushed(app, event)
            global s_e_date_q date_flow met tablename fit1 fit2 fit3
            load('data\floodevents_features\rawfloodeventsdata.mat',"oldflood_f","oldflood_p","T1")
            load('data\recessions\MRCdata.mat','COR_MRCdata')
            
            para(1)=app.aEditField.Value;
            para(2)=app.bEditField.Value;
            x=(COR_MRCdata(1,1):0.01:COR_MRCdata(end,1))';
            if met<4
                y=eval(['fit',num2str(met),'(x)']);
                RE_COR_MRCdata=[x,y];
            else
                load('data\recessions\RE_MRCdata.mat','RE_COR_MRCdata')
            end
            [~,newflood_f,~] = FloodCharacteristics(date_flow,s_e_date_q,RE_COR_MRCdata);
            
            %% post-process
            cor_peaks=newflood_f(:,1);
            %             +app.DeepBaseflowEditField.Value;
            s_e_date_q1=s_e_date_q;
            s_e_date_q1(cor_peaks<=app.ThresholdEditField.Value,:)=[];
            [newflood_p,newflood_f,T2] = FloodCharacteristics(date_flow,s_e_date_q,RE_COR_MRCdata);
            
            app.UITable.Data=T2;
            tablename=2;
            %             cor_oldflood_f=oldflood_f;
            %             cor_oldflood_p=oldflood_p;
            %             cor_T1=T1;
            %             cor_oldflood_f(cor_peaks<=app.ThresholdEditField.Value,:)=[];
            %             cor_oldflood_p(cor_peaks<=app.ThresholdEditField.Value,:)=[];
            %             cor_T1(cor_peaks<=app.ThresholdEditField.Value,:)=[];
            save('data\floodevents_features\floodcharacteristics.mat','newflood_p','newflood_f','T2');
            % 20200617gai
            % save('data\floodevents_features\cor_oldfloodevents.mat','cor_oldflood_p','cor_oldflood_f','cor_T1');%
        end

        % Selection changed function: 
        % RecessionAnalysisMethodsButtonGroup
        function RecessionAnalysisMethodsButtonGroupSelectionChanged(app, event)
            global met
            selectedButton = app.RecessionAnalysisMethodsButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'NoParameter'
                    met=4;
                    app.aEditField.Enable='off';
                    app.bEditField.Enable='off';
                case 'Maillet'
                    met=1;
                    app.aEditField.Enable='on';
                    app.bEditField.Enable='off';
                case 'Coutagne'
                    met=2;
                    app.aEditField.Enable='on';
                    app.bEditField.Enable='on';
                case 'Wittenberg'
                    met=3;
                    app.aEditField.Enable='on';
                    app.bEditField.Enable='on';
            end
        end

        % Button pushed function: OptimizeParameterButton
        function OptimizeParameterButtonPushed(app, event)
            global date_flow
            for ii=0:30
                [rece_series,~]=extract_recession(date_flow,5+ii,app.MovingaverageEditField.Value);
                if length(rece_series)<100
                    %                     ii
                    break
                end
            end
            app.Min_durationEditField.Value=5+ii;
        end

        % Selection changed function: SampleMethodButtonGroup
        function SampleMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.SampleMethodButtonGroup.SelectedObject;
            if selectedButton.Text=='AMS'
                app.CalculateParametersButton.Enable='off';
            else
                app.CalculateParametersButton.Enable='on';
                
            end
            
            
        end

        % Button pushed function: SaveFloodEventsButton
        function SaveFloodEventsButtonPushed(app, event)
            global filename
            load('data\floodevents_features\floodcharacteristics.mat','T2')
            columnname ={'Number','Stardate','Enddate','Peakvalue','Volume','Duration'};
            writecell([columnname ;table2cell(T2)],['data\floodcharacteristics_',filename] )
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SFE_IFC_GUI = uifigure('Visible', 'off');
            app.SFE_IFC_GUI.Name = 'SFE_IFC Toolbox';
            app.UIAxes = uiaxes(app.SFE_IFC_GUI);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Q(m^3/s)')
            app.UITable = uitable(app.SFE_IFC_GUI);
            app.UITable.ColumnName = {'Number'; 'Stardate'; 'Enddate'; 'Peakvalue'; 'Volume'; 'Duration'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false true true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectFloodEventsPanel = uipanel(app.SFE_IFC_GUI);
            app.SelectFloodEventsPanel.Title = 'Select Flood Events';
            app.InputDataPanel = uipanel(app.SelectFloodEventsPanel);
            app.InputDataPanel.Title = 'Input Data';
            app.SelectFileButton = uibutton(app.InputDataPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.SampleMethodButtonGroup = uibuttongroup(app.SelectFloodEventsPanel);
            app.SampleMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SampleMethodButtonGroupSelectionChanged, true);
            app.SampleMethodButtonGroup.Title = 'Sample Method';
            app.POTButton = uiradiobutton(app.SampleMethodButtonGroup);
            app.POTButton.Text = 'POT';
            app.POTButton.Value = true;
            app.AMSButton = uiradiobutton(app.SampleMethodButtonGroup);
            app.AMSButton.Text = 'AMS';
            app.POTParametersPanel = uipanel(app.SelectFloodEventsPanel);
            app.POTParametersPanel.Title = 'POT Parameters';
            app.ThresholdPanel = uipanel(app.POTParametersPanel);
            app.ThresholdPanel.Title = 'Threshold';
            app.ThresholdLabel = uilabel(app.ThresholdPanel);
            app.ThresholdLabel.Text = 'Threshold:';
            app.ThresholdEditField = uieditfield(app.ThresholdPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.Editable = 'off';
            app.PPYLabel = uilabel(app.ThresholdPanel);
            app.PPYLabel.Text = 'PPY：';
            app.PPYEditField = uieditfield(app.ThresholdPanel, 'numeric');
            app.PPYEditField.Limits = [0 Inf];
            app.PPYEditField.Editable = 'off';
            app.ADtestLabel = uilabel(app.ThresholdPanel);
            app.ADtestLabel.Text = 'AD-test:';
            app.ADtestEditField = uieditfield(app.ThresholdPanel, 'numeric');
            app.IntervalPanel = uipanel(app.POTParametersPanel);
            app.IntervalPanel.Title = 'Interval';
            app.MinIntervalLabel = uilabel(app.IntervalPanel);
            app.MinIntervalLabel.Text = 'Min Interval:';
            app.MinIntervalEditField = uieditfield(app.IntervalPanel, 'numeric');
            app.CalculateParametersButton = uibutton(app.POTParametersPanel, 'push');
            app.CalculateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateParametersButtonPushed, true);
            app.CalculateParametersButton.Text = 'Calculate Parameters';
            app.ValidatationPanel = uipanel(app.POTParametersPanel);
            app.ValidatationPanel.Title = 'Validatation';
            app.DispersionindexEditFieldLabel = uilabel(app.ValidatationPanel);
            app.DispersionindexEditFieldLabel.Text = 'Dispersion index:';
            app.DispersionindexEditField = uieditfield(app.ValidatationPanel, 'numeric');
            app.DispersionindexEditField.Editable = 'off';
            app.SelectEventsButton = uibutton(app.SelectFloodEventsPanel, 'push');
            app.SelectEventsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEventsButtonPushed, true);
            app.SelectEventsButton.Text = 'Select Events';
            app.IdentifyFloodCharacteristicsPanel = uipanel(app.SFE_IFC_GUI);
            app.IdentifyFloodCharacteristicsPanel.Title = 'Identify Flood Characteristics';
            app.RecessionCurvesPanel = uipanel(app.IdentifyFloodCharacteristicsPanel);
            app.RecessionCurvesPanel.Title = ' Recession Curves';
            app.ExtractRecessionsPanel = uipanel(app.RecessionCurvesPanel);
            app.ExtractRecessionsPanel.Title = 'Extract Recessions';
            app.MovingaverageLabel = uilabel(app.ExtractRecessionsPanel);
            app.MovingaverageLabel.Text = 'Moving average:';
            app.MovingaverageEditField = uieditfield(app.ExtractRecessionsPanel, 'numeric');
            app.MovingaverageEditField.Value = 1;
            app.Min_durationLabel = uilabel(app.ExtractRecessionsPanel);
            app.Min_durationLabel.Text = 'Min_duration:';
            app.Min_durationEditField = uieditfield(app.ExtractRecessionsPanel, 'numeric');
            app.Min_durationEditField.Value = 5;
            app.OptimizeParameterButton = uibutton(app.ExtractRecessionsPanel, 'push');
            app.OptimizeParameterButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeParameterButtonPushed, true);
            app.OptimizeParameterButton.Text = 'Optimize Parameter';
            app.RecessionAnalysisMethodsButtonGroup = uibuttongroup(app.RecessionCurvesPanel);
            app.RecessionAnalysisMethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RecessionAnalysisMethodsButtonGroupSelectionChanged, true);
            app.RecessionAnalysisMethodsButtonGroup.Title = 'Recession Analysis Methods';
            app.MailletButton = uiradiobutton(app.RecessionAnalysisMethodsButtonGroup);
            app.MailletButton.Text = 'Maillet';
            app.MailletButton.Value = true;
            app.CoutagneButton = uiradiobutton(app.RecessionAnalysisMethodsButtonGroup);
            app.CoutagneButton.Text = 'Coutagne';
            app.WittenbergButton = uiradiobutton(app.RecessionAnalysisMethodsButtonGroup);
            app.WittenbergButton.Text = 'Wittenberg';
            app.NoParameterButton = uiradiobutton(app.RecessionAnalysisMethodsButtonGroup);
            app.NoParameterButton.Text = 'NoParameter';
            app.DeepBaseflowLabel = uilabel(app.RecessionCurvesPanel);
            app.DeepBaseflowLabel.Text = 'Deep Baseflow:';
            app.DeepBaseflowEditField = uieditfield(app.RecessionCurvesPanel, 'numeric');
            app.DeepBaseflowEditField.Editable = 'off';
            app.CalculateParametersPlotButton = uibutton(app.RecessionCurvesPanel, 'push');
            app.CalculateParametersPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateParametersPlotButtonPushed, true);
            app.CalculateParametersPlotButton.Text = 'Calculate Parameters&Plot';
            app.bEditFieldLabel = uilabel(app.RecessionCurvesPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.RecessionCurvesPanel, 'numeric');
            app.bEditField.Editable = 'off';
            app.bEditField.Enable = 'off';
            app.aEditFieldLabel = uilabel(app.RecessionCurvesPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.RecessionCurvesPanel, 'numeric');
            app.aEditField.Editable = 'off';
            app.IdentifyFloodCharacteristicsButton = uibutton(app.IdentifyFloodCharacteristicsPanel, 'push');
            app.IdentifyFloodCharacteristicsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyFloodCharacteristicsButtonPushed, true);
            app.IdentifyFloodCharacteristicsButton.Text = 'Identify Flood Characteristics';
            app.SaveFloodEventsButton = uibutton(app.IdentifyFloodCharacteristicsPanel, 'push');
            app.SaveFloodEventsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFloodEventsButtonPushed, true);
            app.SaveFloodEventsButton.Text = 'Save Flood Events';
            app.GraphicBoxSettingsPanel = uipanel(app.SFE_IFC_GUI);
            app.GraphicBoxSettingsPanel.Title = 'Graphic Box Settings';
            app.CleangraphicboxButton = uibutton(app.GraphicBoxSettingsPanel, 'push');
            app.CleangraphicboxButton.ButtonPushedFcn = createCallbackFcn(app, @CleangraphicboxButtonPushed, true);
            app.CleangraphicboxButton.Text = 'Clean graphic box';
            app.ScaleYDropDownLabel = uilabel(app.GraphicBoxSettingsPanel);
            app.ScaleYDropDownLabel.Text = 'Scale Y:';
            app.ScaleYDropDown = uidropdown(app.GraphicBoxSettingsPanel);
            app.ScaleYDropDown.Items = {'linear', 'log'};
            app.ScaleYDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleYDropDownValueChanged, true);
            app.ScaleYDropDown.Value = 'linear';
            app.ComprehensivePlotCheckBox = uicheckbox(app.GraphicBoxSettingsPanel);
            app.ComprehensivePlotCheckBox.Text = 'Comprehensive Plot';
            app.GoodnessofFitPanel = uipanel(app.SFE_IFC_GUI);
            app.GoodnessofFitPanel.Title = 'Goodness-of-Fit';
            app.SSELabel = uilabel(app.GoodnessofFitPanel);
            app.SSELabel.Text = 'SSE:';
            app.SSEEditField = uieditfield(app.GoodnessofFitPanel, 'numeric');
            app.SSEEditField.Editable = 'off';
            app.SSEEditField.Enable = 'off';
            app.RsquareLabel = uilabel(app.GoodnessofFitPanel);
            app.RsquareLabel.Text = 'R-square:';
            app.RsquareEditField = uieditfield(app.GoodnessofFitPanel, 'numeric');
            app.RsquareEditField.Editable = 'off';
            app.RsquareEditField.Enable = 'off';
            app.RMSELabel = uilabel(app.GoodnessofFitPanel);
            app.RMSELabel.Text = 'RMSE:';
            app.RMSEEditField = uieditfield(app.GoodnessofFitPanel, 'numeric');
            app.RMSEEditField.Editable = 'off';
            app.RMSEEditField.Enable = 'off';
            % Show the figure after all components are created
            app.SFE_IFC_GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SFE_IFC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SFE_IFC_GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SFE_IFC_GUI)
        end
    end
end